classdef GCMI_synergies_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GCMISynergyextractionUIFigure  matlab.ui.Figure
        DataSpecificationsPanel        matlab.ui.container.Panel
        MusclesEditFieldLabel          matlab.ui.control.Label
        MusclesEditField               matlab.ui.control.NumericEditField
        TrialsEditFieldLabel           matlab.ui.control.Label
        TrialsEditField                matlab.ui.control.NumericEditField
        TimepointsEditFieldLabel       matlab.ui.control.Label
        TimepointsEditField            matlab.ui.control.NumericEditField
        ModelSpecificationsPanel       matlab.ui.container.Panel
        SpatialCheckBox                matlab.ui.control.CheckBox
        TemporalCheckBox               matlab.ui.control.CheckBox
        SpaceTimeCheckBox              matlab.ui.control.CheckBox
        Button                         matlab.ui.control.Button
        ImportDataLabel                matlab.ui.control.Label
        OptionalInputPanel             matlab.ui.container.Panel
        ModelrankEditFieldLabel        matlab.ui.control.Label
        ModelrankEditField             matlab.ui.control.NumericEditField
        ManualmodelrankselectionCheckBox  matlab.ui.control.CheckBox
        OptionalOutputPanel            matlab.ui.container.Panel
        PlotSynergiesCheckBox          matlab.ui.control.CheckBox
        ManualthresholdCheckBox        matlab.ui.control.CheckBox
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        StartLabel                     matlab.ui.control.Label
        Button_2                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        X % Data
        timepoints %No. of timepoints
        muscles %No. of muscles
        Trials %No. of trials
        S % If spatial synergies are selected
        T % If temporal synergies are selected
        ST %If space-time synergies are selected
        progress %update statement
        manual_rank %Manual rank 
        manual_thresh %Manual threshold
        plots
    end
    
    properties (Access = public)
        Spatial_GCMI %Spatial GCMI output
        Temporal_GCMI %Temporal GCMI output
        SpaceTime_GCMI %Space Time GCMI output
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path,indx]=uigetfile;
            filename=[path file];
            app.X=importdata(filename);
            disp('Data loaded...(X variable)');
            app.ModelrankEditField.Visible = 'off';
            app.ThresholdEditField.Visible = 'off';
        end

        % Value changed function: TimepointsEditField
        function TimepointsEditFieldValueChanged(app, event)
            app.timepoints = app.TimepointsEditField.Value;
            
        end

        % Value changed function: MusclesEditField
        function MusclesEditFieldValueChanged(app, event)
            app.muscles = app.MusclesEditField.Value;
            
        end

        % Value changed function: TrialsEditField
        function TrialsEditFieldValueChanged(app, event)
            app.Trials = app.TrialsEditField.Value;
            
        end

        % Value changed function: SpatialCheckBox
        function SpatialCheckBoxValueChanged(app, event)
             value=app.SpatialCheckBox.Value;
             
             switch value
                 case 0
                     clear app.S;
                 case 1
                     app.S='S';
             end
            
        end

        % Value changed function: TemporalCheckBox
        function TemporalCheckBoxValueChanged(app, event)
            value = app.TemporalCheckBox.Value;
            
            switch value
                 case 0
                     clear app.T;
                 case 1
                     app.T='T';
             end
        end

        % Value changed function: SpaceTimeCheckBox
        function SpaceTimeCheckBoxValueChanged(app, event)
            value = app.SpaceTimeCheckBox.Value;
            
            switch value
                 case 0
                     clear app.ST;
                 case 1
                     app.ST='ST';
             end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.S=='S'
                for i=1:app.Trials
                    Mi_Spatial.(sprintf('Trial_%d',i))=[];
                end

                trial_len=length(app.X)/app.Trials;
                x=mat2tiles(app.X,[trial_len,size(app.X,2)]);
                combos=nchoosek(1:app.muscles,2);
                x=cat(3,x{:});

                for i=1:app.Trials
                    app.progress=i;
                    fprintf('Computing Spatial GCMI on trial #%d \n',app.progress);
                    trial=reshape(x(:,:,i),[trial_len,app.muscles]);
                    for ii=1:length(combos)
                        I=gcmi_cc(trial(:,combos(ii,1)), trial(:,combos(ii,2)));
                        Mi_Spatial.(sprintf('Trial_%d',i))=[Mi_Spatial.(sprintf('Trial_%d',i));I];
                    end
                    Mi_Spatial.(sprintf('Trial_%d',i))=Mi_Spatial.(sprintf('Trial_%d',i))';
                end

                SP=cell2mat(struct2cell(Mi_Spatial));
                if app.ManualmodelrankselectionCheckBox.Value==0 && app.ManualthresholdCheckBox.Value==0
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SP,'S',app.muscles);
                    app.Spatial_GCMI = struct;
                    app.Spatial_GCMI.Mat_unthresholded=SP';
                    app.Spatial_GCMI.Mat_thresholded=Mat_thresholded;
                    app.Spatial_GCMI.Thresholds=Thresholds;
                    app.Spatial_GCMI.Opt_rank=Opt_rank;
                    app.Spatial_GCMI.Q=Q;
                    app.Spatial_GCMI.S=S;
                    [W,H]=opnmf(Mat_thresholded,Opt_rank);
                    app.Spatial_GCMI.PNMF.W=W;
                    app.Spatial_GCMI.PNMF.H=H;
                    assignin('base','Spatial_GCMI',app.Spatial_GCMI);
                elseif app.ManualmodelrankselectionCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SP,'S',app.muscles);
                    app.Spatial_GCMI = struct;
                    app.Spatial_GCMI.Mat_unthresholded=SP';
                    app.Spatial_GCMI.Mat_thresholded=Mat_thresholded;
                    app.Spatial_GCMI.Thresholds=Thresholds;
                    [W,H]=opnmf(Mat_thresholded,app.manual_rank);
                    app.Spatial_GCMI.PNMF.W=W;
                    app.Spatial_GCMI.PNMF.H=H;
                    assignin('base','Spatial_GCMI',app.Spatial_GCMI);
                elseif app.ManualthresholdCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SP,'S',app.muscles);
                    app.Spatial_GCMI = struct;
                    app.Spatial_GCMI.Mat_unthresholded=SP';
                    Mat_thresholded=SP';
                    Mat_thresholded(Mat_thresholded<app.manual_thresh)=0;
                    app.Spatial_GCMI.Mat_thresholded=Mat_thresholded;
                    app.Spatial_GCMI.Opt_rank=Opt_rank;
                    app.Spatial_GCMI.Q=Q;
                    app.Spatial_GCMI.S=S;
                    [W,H]=opnmf(Mat_thresholded,Opt_rank);
                    app.Spatial_GCMI.PNMF.W=W;
                    app.Spatial_GCMI.PNMF.H=H;
                    assignin('base','Spatial_GCMI',app.Spatial_GCMI);
                elseif app.ManualthresholdCheckBox.Value==1 && app.ManualmodelrankselectionCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SP,'S',app.muscles);
                    app.Spatial_GCMI = struct;
                    app.Spatial_GCMI.Mat_unthresholded=SP';
                    Mat_thresholded=SP';
                    Mat_thresholded(Mat_thresholded<app.manual_thresh)=0;
                    app.Spatial_GCMI.Mat_thresholded=Mat_thresholded;
                    [W,H]=opnmf(Mat_thresholded,app.manual_rank);
                    app.Spatial_GCMI.PNMF.W=W;
                    app.Spatial_GCMI.PNMF.H=H;
                    assignin('base','Spatial_GCMI',app.Spatial_GCMI);
                end
                
                if app.plots==1 && app.ManualmodelrankselectionCheckBox.Value==0
                    [FIG1,FIG2]=plot_gcmisynergy(W,H,'S',Opt_rank,[app.timepoints,app.muscles]);
                elseif app.plots==1 && app.ManualmodelrankselectionCheckBox.Value==1
                    [FIG1,FIG2]=plot_gcmisynergy(W,H,'S',app.manual_rank,[app.timepoints,app.muscles]);
                end
            end
            
            if app.T=='T'
                Mi_Temporal=[];
                for i=1:app.Trials
                    Mi_Temporal.(sprintf('Trial_%d',i))=[];
                end

                trial_len=length(app.X)/app.Trials;
                x=mat2tiles(app.X,[trial_len,size(app.X,2)]);
                combos=nchoosek(1:size(x{1},1),2);
                x=permute(cat(3,x{:}),[2,1,3]);

                for i=1:app.Trials
                    trial=copnorm(x(:,:,i));
                    Is=[];
                    app.progress=i;
                    fprintf('Computing Temporal GCMI on trial #%d \n',app.progress);
                    for s=1:length(combos)
                        try
                            Is=[Is;mi_gg(trial(:,combos(s,1)), trial(:,combos(s,2)))];
                        catch message
                            Is=[Is;ent_g(trial(:,combos(s,1)))];
                        end
                    end
                    Mi_Temporal.(sprintf('Trial_%d',i))=[Mi_Temporal.(sprintf('Trial_%d',i));Is'];
                end
                
                TM=cell2mat(struct2cell(Mi_Temporal));
                
                if app.ManualmodelrankselectionCheckBox.Value==0 && app.ManualthresholdCheckBox.Value==0
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(TM,'T',app.timepoints);
                    app.Temporal_GCMI = struct;
                    app.Temporal_GCMI.Mat_unthresholded=TM';
                    app.Temporal_GCMI.Mat_thresholded=Mat_thresholded;
                    app.Temporal_GCMI.Thresholds=Thresholds;
                    app.Temporal_GCMI.Opt_rank=Opt_rank;
                    app.Temporal_GCMI.Q=Q;
                    app.Temporal_GCMI.S=S;
                    [W,H]=opnmf(Mat_thresholded,Opt_rank);
                    app.Temporal_GCMI.PNMF.W=W;
                    app.Temporal_GCMI.PNMF.H=H;
                    assignin('base','Temporal_GCMI',app.Temporal_GCMI);
                elseif app.ManualmodelrankselectionCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(TM,'T',app.timepoints);
                    app.Temporal_GCMI = struct;
                    app.Temporal_GCMI.Mat_unthresholded=TM';
                    app.Temporal_GCMI.Mat_thresholded=Mat_thresholded;
                    app.Temporal_GCMI.Thresholds=Thresholds;
                    [W,H]=opnmf(Mat_thresholded,app.manual_rank);
                    app.Temporal_GCMI.PNMF.W=W;
                    app.Temporal_GCMI.PNMF.H=H;
                    assignin('base','Temporal_GCMI',app.Temporal_GCMI);
                elseif app.ManualthresholdCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(TM,'T',app.timepoints);
                    app.Temporal_GCMI = struct;
                    app.Temporal_GCMI.Mat_unthresholded=TM';
                    Mat_thresholded=TM';
                    Mat_thresholded(Mat_thresholded<app.manual_thresh)=0;
                    app.Temporal_GCMI.Mat_thresholded=Mat_thresholded;
                    app.Temporal_GCMI.Opt_rank=Opt_rank;
                    app.Temporal_GCMI.Q=Q;
                    app.Temporal_GCMI.S=S;
                    [W,H]=opnmf(Mat_thresholded,Opt_rank);
                    app.Temporal_GCMI.PNMF.W=W;
                    app.Temporal_GCMI.PNMF.H=H;
                    assignin('base','Temporal_GCMI',app.Temporal_GCMI);
                elseif app.ManualthresholdCheckBox.Value==1 && app.ManualmodelrankselectionCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(TM,'T',app.timepoints);
                    app.Temporal_GCMI = struct;
                    app.Temporal_GCMI.Mat_unthresholded=TM';
                    Mat_thresholded=TM';
                    Mat_thresholded(Mat_thresholded<app.manual_thresh)=0;
                    app.Temporal_GCMI.Mat_thresholded=Mat_thresholded;
                    [W,H]=opnmf(Mat_thresholded,app.manual_rank);
                    app.Temporal_GCMI.PNMF.W=W;
                    app.Temporal_GCMI.PNMF.H=H;
                    assignin('base','Temporal_GCMI',app.Temporal_GCMI);
                end
                
                if app.plots==1 && app.ManualmodelrankselectionCheckBox.Value==0
                    [FIG1,FIG2]=plot_gcmisynergy(W,H,'T',Opt_rank,[app.muscles,app.timepoints]);
                elseif app.plots==1 && app.ManualmodelrankselectionCheckBox.Value==1
                    [FIG1,FIG2]=plot_gcmisynergy(W,H,'T',app.manual_rank,[app.muscles,app.timepoints]);
                end
            end
            
            if strcmp(app.ST,'ST')
                
                x=mat2tiles(app.X,[app.timepoints,app.muscles]);


                combos_time=nchoosek(1:length(x{1}),2);
                combos=nchoosek(1:size(x{1},2),2);
                x=cat(3,x{:});

                MIs=[];
                for i=1:length(combos_time)
                    for ii=1:length(combos)
                        app.progress=i;
                        fprintf(['Computing Space-Time GCMI on timepoint pairing #%d ' ...
                            '& Spatial pairing #%d \n'],i,ii);
                        vars1=x(combos_time(i,1),combos(ii,1),:);
                        vars2=x(combos_time(i,2),combos(ii,2),:);
                        I=gcmi_cc(vars1(:),vars2(:));
                        MIs=[MIs;I];
                    end
                end
                sp=reshape(MIs,[length(combos),length(combos_time)]);


                MIs_linear=[];
                for i=1:app.timepoints
                    for ii=1:length(combos)
                        vars1=x(i,combos(ii,1),:);
                        vars2=x(i,combos(ii,2),:);
                        I=gcmi_cc(vars1(:),vars2(:));
                        MIs_linear=[MIs_linear;I];
                    end
                end
                sp2=reshape(MIs_linear,[length(combos),app.timepoints]);


                SPTM=[sp2,sp];
                
                if app.ManualmodelrankselectionCheckBox.Value==0 && app.ManualthresholdCheckBox.Value==0
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SPTM,'ST',[app.timepoints,app.muscles]);
                    app.SpaceTime_GCMI = struct;
                    app.SpaceTime_GCMI.Mat_unthresholded=SPTM';
                    app.SpaceTime_GCMI.Mat_thresholded=Mat_thresholded;
                    app.SpaceTime_GCMI.Thresholds=Thresholds;
                    app.SpaceTime_GCMI.Opt_rank=Opt_rank;
                    app.SpaceTime_GCMI.Q=Q;
                    app.SpaceTime_GCMI.S=S;
                    [W,H]=opnmf(Mat_thresholded,Opt_rank);
                    app.SpaceTime_GCMI.PNMF.W=W;
                    app.SpaceTime_GCMI.PNMF.H=H;
                    assignin('base','SpaceTime_GCMI',app.SpaceTime_GCMI);
                elseif app.ManualmodelrankselectionCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SPTM,'ST',[app.timepoints,app.muscles]);
                    app.SpaceTime_GCMI = struct;
                    app.SpaceTime_GCMI.Mat_unthresholded=SPTM';
                    app.SpaceTime_GCMI.Mat_thresholded=Mat_thresholded;
                    app.SpaceTime_GCMI.Thresholds=Thresholds;
                    [W,H]=opnmf(Mat_thresholded,app.manual_rank);
                    app.SpaceTime_GCMI.PNMF.W=W;
                    app.SpaceTime_GCMI.PNMF.H=H;
                    assignin('base','SpaceTime_GCMI',app.SpaceTime_GCMI);
                elseif app.ManualthresholdCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SPTM,'ST',[app.timepoints,app.muscles]);
                    app.SpaceTime_GCMI = struct;
                    app.SpaceTime_GCMI.Mat_unthresholded=SPTM';
                    Mat_thresholded=SPTM';
                    Mat_thresholded(Mat_thresholded<app.manual_thresh)=0;
                    app.SpaceTime_GCMI.Mat_thresholded=Mat_thresholded;
                    app.SpaceTime_GCMI.Opt_rank=Opt_rank;
                    app.Spatial_GCMI.Q=Q;
                    app.Spatial_GCMI.S=S;
                    [W,H]=opnmf(Mat_thresholded,Opt_rank);
                    app.SpaceTime_GCMI.PNMF.W=W;
                    app.SpaceTime_GCMI.PNMF.H=H;
                    assignin('base','SpaceTime_GCMI',app.SpaceTime_GCMI);
                elseif app.ManualthresholdCheckBox.Value==1 && app.ManualmodelrankselectionCheckBox.Value==1
                    [Mat_thresholded, Thresholds, Opt_rank, Q, S]=CD_Thresholding(SPTM,'ST',[app.timepoints,app.muscles]);
                    app.SpaceTime_GCMI = struct;
                    app.SpaceTime_GCMI.Mat_unthresholded=SPTM';
                    Mat_thresholded=SPTM';
                    Mat_thresholded(Mat_thresholded<app.manual_thresh)=0;
                    app.SpaceTime_GCMI.Mat_thresholded=Mat_thresholded;
                    [W,H]=opnmf(Mat_thresholded,app.manual_rank);
                    app.SpaceTime_GCMI.PNMF.W=W;
                    app.SpaceTime_GCMI.PNMF.H=H;
                    assignin('base','SpaceTime_GCMI',app.SpaceTime_GCMI);
                end
                
                if app.plots==1 && app.ManualmodelrankselectionCheckBox.Value==0
                    [FIG1,FIG2]=plot_gcmisynergy(W,H,'ST',Opt_rank,[app.timepoints,app.muscles]);
                elseif app.plots==1 && app.ManualmodelrankselectionCheckBox.Value==1
                    [FIG1,FIG2]=plot_gcmisynergy(W,H,'ST',app.manual_rank,[app.timepoints,app.muscles]);
                end
            end
            
            disp('Finished....')
        end

        % Value changed function: ManualmodelrankselectionCheckBox
        function ManualmodelrankselectionCheckBoxValueChanged(app, event)
            value = app.ManualmodelrankselectionCheckBox.Value;
            if value==1
                  app.ModelrankEditField.Visible = 'on';
            else
                  app.ModelrankEditField.Visible = 'off';
                  
            end
        end

        % Value changed function: ModelrankEditField
        function ModelrankEditFieldValueChanged(app, event)
            app.manual_rank = app.ModelrankEditField.Value;
            if app.ManualmodelrankselectionCheckBox.Value==0
                app.ModelrankEditField.Visible = 'on'; 
            else
                app.ModelrankEditField.Visible = 'off';
            end
        end

        % Value changed function: ManualthresholdCheckBox
        function ManualthresholdCheckBoxValueChanged(app, event)
            value = app.ManualthresholdCheckBox.Value;
            if value==1
                  app.ThresholdEditField.Visible = 'on';
            else
                  app.ThresholdEditField.Visible = 'off';
                  
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.manual_thresh = app.ThresholdEditField.Value;
            if app.ManualthresholdCheckBox.Value==0
                app.ThresholdEditField.Visible = 'on'; 
            else
                app.ThresholdEditField.Visible = 'off';
            end
        end

        % Value changed function: PlotSynergiesCheckBox
        function PlotSynergiesCheckBoxValueChanged(app, event)
            app.plots = app.PlotSynergiesCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GCMISynergyextractionUIFigure = uifigure('Visible', 'off');
            app.GCMISynergyextractionUIFigure.Name = 'GCMI Synergy extraction';
            app.DataSpecificationsPanel = uipanel(app.GCMISynergyextractionUIFigure);
            app.DataSpecificationsPanel.Title = 'Data Specifications';
            app.MusclesEditFieldLabel = uilabel(app.DataSpecificationsPanel);
            app.MusclesEditFieldLabel.Text = 'Muscles';
            app.MusclesEditField = uieditfield(app.DataSpecificationsPanel, 'numeric');
            app.MusclesEditField.ValueChangedFcn = createCallbackFcn(app, @MusclesEditFieldValueChanged, true);
            app.TrialsEditFieldLabel = uilabel(app.DataSpecificationsPanel);
            app.TrialsEditFieldLabel.Text = 'Trials';
            app.TrialsEditField = uieditfield(app.DataSpecificationsPanel, 'numeric');
            app.TrialsEditField.ValueChangedFcn = createCallbackFcn(app, @TrialsEditFieldValueChanged, true);
            app.TimepointsEditFieldLabel = uilabel(app.DataSpecificationsPanel);
            app.TimepointsEditFieldLabel.Text = 'Timepoints';
            app.TimepointsEditField = uieditfield(app.DataSpecificationsPanel, 'numeric');
            app.TimepointsEditField.ValueChangedFcn = createCallbackFcn(app, @TimepointsEditFieldValueChanged, true);
            app.ModelSpecificationsPanel = uipanel(app.GCMISynergyextractionUIFigure);
            app.ModelSpecificationsPanel.Title = 'Model Specifications';
            app.SpatialCheckBox = uicheckbox(app.ModelSpecificationsPanel);
            app.SpatialCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpatialCheckBoxValueChanged, true);
            app.SpatialCheckBox.Text = 'Spatial';
            app.TemporalCheckBox = uicheckbox(app.ModelSpecificationsPanel);
            app.TemporalCheckBox.ValueChangedFcn = createCallbackFcn(app, @TemporalCheckBoxValueChanged, true);
            app.TemporalCheckBox.Text = 'Temporal';
            app.SpaceTimeCheckBox = uicheckbox(app.ModelSpecificationsPanel);
            app.SpaceTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpaceTimeCheckBoxValueChanged, true);
            app.SpaceTimeCheckBox.Text = 'Space-Time';
            app.Button = uibutton(app.GCMISynergyextractionUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'download.png';
            app.Button.Text = '';
            app.ImportDataLabel = uilabel(app.GCMISynergyextractionUIFigure);
            app.ImportDataLabel.Text = 'Import Data';
            app.OptionalInputPanel = uipanel(app.GCMISynergyextractionUIFigure);
            app.OptionalInputPanel.Title = 'Optional Input';
            app.ModelrankEditFieldLabel = uilabel(app.OptionalInputPanel);
            app.ModelrankEditFieldLabel.Text = 'Model rank';
            app.ModelrankEditField = uieditfield(app.OptionalInputPanel, 'numeric');
            app.ModelrankEditField.ValueChangedFcn = createCallbackFcn(app, @ModelrankEditFieldValueChanged, true);
            app.ManualmodelrankselectionCheckBox = uicheckbox(app.OptionalInputPanel);
            app.ManualmodelrankselectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualmodelrankselectionCheckBoxValueChanged, true);
            app.ManualmodelrankselectionCheckBox.Text = 'Manual model rank selection';
            app.OptionalOutputPanel = uipanel(app.OptionalInputPanel);
            app.OptionalOutputPanel.Title = 'Optional Output';
            app.PlotSynergiesCheckBox = uicheckbox(app.OptionalOutputPanel);
            app.PlotSynergiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotSynergiesCheckBoxValueChanged, true);
            app.PlotSynergiesCheckBox.Text = 'Plot Synergies';
            app.ManualthresholdCheckBox = uicheckbox(app.OptionalInputPanel);
            app.ManualthresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualthresholdCheckBoxValueChanged, true);
            app.ManualthresholdCheckBox.Text = 'Manual threshold';
            app.ThresholdEditFieldLabel = uilabel(app.OptionalInputPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.OptionalInputPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.StartLabel = uilabel(app.GCMISynergyextractionUIFigure);
            app.StartLabel.Text = 'Start';
            app.Button_2 = uibutton(app.GCMISynergyextractionUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'ef07471474a0e1086a185086c342ae00.jpg';
            app.Button_2.Text = '';
            % Show the figure after all components are created
            app.GCMISynergyextractionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GCMI_synergies_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GCMISynergyextractionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GCMISynergyextractionUIFigure)
        end
    end
end